classdef SponAsMod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridCheckBox_3               matlab.ui.control.CheckBox
        GridCheckBox                 matlab.ui.control.CheckBox
        PlotCainERButton             matlab.ui.control.Button
        PlotIP3Button                matlab.ui.control.Button
        PlotCaButton                 matlab.ui.control.Button
        TabGroup2                    matlab.ui.container.TabGroup
        ExperimentalTracesTab        matlab.ui.container.Tab
        Tree                         matlab.ui.container.CheckBoxTree
        ReactiveNode_2               matlab.ui.container.TreeNode
        Trace1Node                   matlab.ui.container.TreeNode
        Trace2Node                   matlab.ui.container.TreeNode
        Trace3Node                   matlab.ui.container.TreeNode
        PolygonalNode                matlab.ui.container.TreeNode
        Trace1Node_3                 matlab.ui.container.TreeNode
        Trace2Node_3                 matlab.ui.container.TreeNode
        Trace1Node_4                 matlab.ui.container.TreeNode
        StellateNode                 matlab.ui.container.TreeNode
        Trace1Node_2                 matlab.ui.container.TreeNode
        Trace2Node_2                 matlab.ui.container.TreeNode
        Trace3Node_2                 matlab.ui.container.TreeNode
        ExperimentalTracesDataTab_2  matlab.ui.container.Tab
        Tracesdata                   matlab.ui.control.Table
        VinSlider                    matlab.ui.control.Slider
        FlowofCalciumfromtheextracellularspacetothecytosolLabel  matlab.ui.control.Label
        uMLabel_2                    matlab.ui.control.Label
        uMsLabel_3                   matlab.ui.control.Label
        uMLabel                      matlab.ui.control.Label
        uMLabel_3                    matlab.ui.control.Label
        uMsLabel_2                   matlab.ui.control.Label
        kCaIEditField                matlab.ui.control.NumericEditField
        VM3EditField                 matlab.ui.control.NumericEditField
        k_outSlider                  matlab.ui.control.Slider
        k_outSliderLabel             matlab.ui.control.Label
        VM3Slider                    matlab.ui.control.Slider
        VM3SliderLabel               matlab.ui.control.Label
        KpSlider                     matlab.ui.control.Slider
        KpSliderLabel                matlab.ui.control.Label
        kPEditField                  matlab.ui.control.NumericEditField
        kCaAEditField                matlab.ui.control.NumericEditField
        VM2EditField                 matlab.ui.control.NumericEditField
        kfSlider                     matlab.ui.control.Slider
        kfSliderLabel                matlab.ui.control.Label
        VM2Slider                    matlab.ui.control.Slider
        VM2SliderLabel               matlab.ui.control.Label
        Reset                        matlab.ui.control.Button
        VinEditField                 matlab.ui.control.NumericEditField
        uMsLabel                     matlab.ui.control.Label
        SpontaneousCalciumOscillationsinAstrocytesLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        PlotsTab                     matlab.ui.container.Tab
        GridCheckBox_2               matlab.ui.control.CheckBox
        PrintButton                  matlab.ui.control.StateButton
        IP3CAxes                     matlab.ui.control.UIAxes
        CaERAxes                     matlab.ui.control.UIAxes
        CaCAxes                      matlab.ui.control.UIAxes
        FullModelTab                 matlab.ui.container.Tab
        GridCheckBox_4               matlab.ui.control.CheckBox
        PlotfullmodelButton          matlab.ui.control.Button
        FMAxes                       matlab.ui.control.UIAxes
        CytosolVariablesPanel        matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
    end

    
  %create a new internal set of parameters for the differential equations 
    properties (Access = public)
       Traces % Experimental astrocyte traces (Nick)
       params = ModelParameters(); %making the eq parmeters global variables
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %load traces
           load('astrocyte_traces.mat','traces_R','traces_S','traces_P');
           app.Traces = table(traces_R,traces_S,traces_P);
           app.Tracesdata.Data = app.Traces;
            
            %use stratup fnct to add classes and hope for the best
   
        import ModelParameters.*
        import ModelSystem.*
        import ode45.*
             

           %Design
        app.PlotsTab.FontName = 'Optima';
%         app.CalciumConcentrationintheERTab.FontName = 'Optima';
%         app.FullModelTab.FontName = 'Optima';
%         app.IP3ConcentrationTab.FontName = 'Optima';

        %fix the mf tabs

       % app.CaERAxes = uiaxes(app.PlotsTab);


        end

        % Button pushed function: PlotCaButton
        function PlotCaButtonPushed(app, event)
            %plotting the trace
%           load('astrocyte_traces.mat','traces_R'); 
%         plot(app.CaCAxes,traces_R(3,:));
%   getting rid of it for the time being    
app.PlotCaButton.Visible = 'off';

%SCRIPT TO PLOT USING BUTTON AS A PLOT FUNCTION
            
            % Create an instance of ModelParameters and ModelSystem
            % dummy#1 added params as local variable
         %params = ModelParameters();
%         params.v_in = app.VinSlider.Value;
%         params.k_p = app.KpSlider.Value;
%         params.v_M2 = app.VM2Slider.Value;
%         params.v_M3 = app.VM3Slider.Value;
%         params.k_CaA = app.kfSlider.Value;
%         params.k_CaI = app.k_outSlider.Value;

%         %dummy#2 added params as global variables 
%         app.params.v_in = app.VinSlider.Value;
%         app.params.k_p = app.KpSlider.Value;
%         app.params.v_M2 = app.VM2Slider.Value;
%         app.params.v_M3 = app.VM3Slider.Value;
%         app.params.k_CaA = app.kfSlider.Value;
%         app.params.k_CaI = app.k_outSlider.Value;
%         mySys = ModelSystem(app.params);
%     

        app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        %app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        %app.params.k_CaA = app.kfSlider.Value;
        %app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params); 


            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot the results
        
        plot(app.CaCAxes,t, data(:, 1));
        app.CaCAxes.YLimMode = 'auto';

        %Print current plot(?)
        %app.CaCAxes.NextPlot = 'add';

        end

        % Button pushed function: PlotIP3Button
        function PlotIP3ButtonPushed(app, event)
            app.PlotIP3Button.Visible = 'off';
            % Create an instance of ModelParameters and ModelSystem
        app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        %app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        %app.params.k_CaA = app.kfSlider.Value;
        %app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params);

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot the results
        plot(app.IP3CAxes, t, data(:, 3));
        app.IP3CAxes.YLimMode = 'auto';

        end

        % Button pushed function: PlotCainERButton
        function PlotCainERButtonPushed(app, event)
            app.PlotCainERButton.Visible = 'off';     
            % Create an instance of ModelParameters and ModelSystem
        app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params);

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot the results
        
        plot(app.CaERAxes, t, data(:, 2));
        app.CaERAxes.YLimMode = 'auto';

        end

        % Button pushed function: PlotfullmodelButton
        function PlotfullmodelButtonPushed(app, event)
%app.PlotCaButton.Visible = 'off';
   
            % Create an instance of ModelParameters and ModelSystem
        app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params);

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot the results
        
        plot(app.FMAxes,t, data(:, 1),"Color",'magenta');
        plot(app.FMAxes, t, data(:, 2),'Color','cyan');
        plot(app.FMAxes, t, data(:, 3),'color','green');
        app.FMAxes.YLimMode = 'auto';

           
        end

        % Value changed function: VinSlider
        function VinSliderValueChanged(app, event)
        Vin = event.Value;            
        app.VinEditField.Value = Vin; %Connecting slider to EF
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
          %clearing the graphs
            cla(app.CaCAxes);
            cla(app.CaERAxes);
            cla(app.IP3CAxes);
            cla(app.FMAxes);

          %Resetting values
        app.VinSlider.Value = 0.05;
        app.KpSlider.Value = 0.3;
        app.VM2Slider.Value = 15;
        app.VM3Slider.Value = 40;
        app.kfSlider.Value = 0.15;
        app.k_outSlider.Value = 0.15;
        app.VinEditField.Value = 0.05;
        app.kPEditField.Value = 0.3;
        app.VM2EditField.Value = 15;
        app.VM3EditField.Value = 40;
        app.kCaAEditField.Value = 0.15;
        app.kCaIEditField.Value = 0.15;
        end

        % Value changed function: VM3EditField
        function VM3EditFieldValueChanged(app, event)
           VM3 = event.Value;
           app.VM3Slider.Value = VM3;
        end

        % Value changed function: k_outSlider
        function k_outSliderValueChanged(app, event)
           kCAI = event.Value;
           app.kCaIEditField.Value = kCAI;
            
        end

        % Value changed function: VM3Slider
        function VM3SliderValueChanged(app, event)
            VM3 = event.Value;
            app.VM3EditField.Value = VM3;
   
        end

        % Value changed function: KpSlider
        function KpSliderValueChanged(app, event)
            kp = event.Value;
            app.kPEditField.Value = kp;
            
        end

        % Value changed function: kPEditField
        function kPEditFieldValueChanged(app, event)
            kp = event.Value;
            app.KpSlider.Value = kp;
            
        end

        % Value changed function: kCaAEditField
        function kCaAEditFieldValueChanged(app, event)
            kCaA = event.Value;
            app.kfSlider.Value = kCaA;
            
        end

        % Value changed function: VM2EditField
        function VM2EditFieldValueChanged(app, event)
            VM2 = event.Value;
            app.VM2Slider.Value = VM2;
            
        end

        % Value changed function: kfSlider
        function kfSliderValueChanged(app, event)
            kCaA = event.Value;
            app.kCaAEditField.Value = kCaA;
            
        end

        % Value changed function: VM2Slider
        function VM2SliderValueChanged(app, event)
            VM2 = event.Value;
            app.VM2EditField.Value = VM2;
            
        end

        % Value changed function: VinEditField
        function VinEditFieldValueChanged(app, event)
            vin = event.Value;
            app.VinSlider.Value = vin;
            
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
%             if app.GridCheckBox.Value
%                 app.CaCAxes.XGrid = 'on';
%                 app.CaCAxes.YGrid = 'on';
%                 app.CaCAxes.XMinorGrid = 'on';
%                 app.CaCAxes.YMinorGrid = 'on';
%           else
%              app.CaCAxes.XGrid = 'off';
%              app.CaCAxes.YGrid = 'off';
%              app.CaCAxes.XMinorGrid = 'off';
%             app.CaCAxes.YMinorGrid = 'off';
%             end
            
        end

        % Value changed function: GridCheckBox_2
        function GridCheckBox_2ValueChanged(app, event)
             if app.GridCheckBox_2.Value
                app.IP3CAxes.XGrid = 'on';
                app.IP3CAxes.YGrid = 'on';
                app.IP3CAxes.XMinorGrid = 'on';
                app.IP3CAxes.YMinorGrid = 'on';
                app.CaERAxes.XGrid = 'on';
                app.CaERAxes.YGrid = 'on';
                app.CaERAxes.XMinorGrid = 'on';
                app.CaERAxes.YMinorGrid = 'on';
                app.CaCAxes.XGrid = 'on';
                app.CaCAxes.YGrid = 'on';
                app.CaCAxes.XMinorGrid = 'on';
                app.CaCAxes.YMinorGrid = 'on';
          else
             app.IP3CAxes.XGrid = 'off';
             app.IP3CAxes.YGrid = 'off';
             app.IP3CAxes.XMinorGrid = 'off';
            app.IP3CAxes.YMinorGrid = 'off';
            app.CaERAxes.XGrid = 'off';
             app.CaERAxes.YGrid = 'off';
             app.CaERAxes.XMinorGrid = 'off';
             app.CaERAxes.YMinorGrid = 'off';
             app.CaCAxes.XGrid = 'off';
             app.CaCAxes.YGrid = 'off';
             app.CaCAxes.XMinorGrid = 'off';
            app.CaCAxes.YMinorGrid = 'off';
            end

            
        end

        % Value changed function: GridCheckBox_3
        function GridCheckBox_3ValueChanged(app, event)
%            if app.GridCheckBox_3.Value
%                 app.CaERAxes.XGrid = 'on';
%                 app.CaERAxes.YGrid = 'on';
%                 app.CaERAxes.XMinorGrid = 'on';
%                 app.CaERAxes.YMinorGrid = 'on';
%           else
%              app.CaERAxes.XGrid = 'off';
%              app.CaERAxes.YGrid = 'off';
%              app.CaERAxes.XMinorGrid = 'off';
%              app.CaERAxes.YMinorGrid = 'off';
%             end
            
            
        end

        % Value changing function: VinSlider
        function VinSliderValueChanging(app, event)
         app.params.v_in = event.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params);
    

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot the results
        
        plot(app.CaCAxes,t, data(:, 1));
        app.CaCAxes.YLimMode = 'auto';
            
        end

        % Value changed function: PrintButton
        function PrintButtonValueChanged(app, event)
%             app.PrintButton 
%             hold(app.CaCAxes) 
         %app.CaCAxes.NextPlot = 'add';


            if  app.PrintButton.Value 
               hold(app.CaCAxes) 
             else 
                waitfor(app.PrintButton,PrintButtonValueChanged(app,event)) 
%                  PrintButtonValueChanged(app,)
%                %app.PrintButton.Value = 'false';
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.CytosolVariablesPanel = uipanel(app.UIFigure);
            app.CytosolVariablesPanel.Title = 'Cytosol Variables ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.CaCAxes = uiaxes(app.PlotsTab);
            title(app.CaCAxes, 'Calcium Concentration')
            xlabel(app.CaCAxes, 'Time (s)')
            ylabel(app.CaCAxes, 'Ca (uM)')
            zlabel(app.CaCAxes, 'Z')
            app.CaERAxes = uiaxes(app.PlotsTab);
            title(app.CaERAxes, 'Calcium Concentration in the ER')
            xlabel(app.CaERAxes, 'Time (s)')
            ylabel(app.CaERAxes, 'CaER (uM)')
            zlabel(app.CaERAxes, 'Z')
            app.IP3CAxes = uiaxes(app.PlotsTab);
            title(app.IP3CAxes, 'IP3 Concentration')
            xlabel(app.IP3CAxes, 'Time (s)')
            ylabel(app.IP3CAxes, 'IP3 (uM)')
            zlabel(app.IP3CAxes, 'Z')
            app.PrintButton = uibutton(app.PlotsTab, 'state');
            app.PrintButton.ValueChangedFcn = createCallbackFcn(app, @PrintButtonValueChanged, true);
            app.PrintButton.Text = 'Print';
            app.GridCheckBox_2 = uicheckbox(app.PlotsTab);
            app.GridCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GridCheckBox_2ValueChanged, true);
            app.GridCheckBox_2.Text = 'Grid';
            app.FullModelTab = uitab(app.TabGroup);
            app.FullModelTab.Title = 'Full Model';
            app.FMAxes = uiaxes(app.FullModelTab);
            title(app.FMAxes, 'Combined Model')
            xlabel(app.FMAxes, 'Time (s)')
            ylabel(app.FMAxes, 'Concentration (uM)')
            zlabel(app.FMAxes, 'Z')
            app.FMAxes.SubtitleFontWeight = 'bold';
            app.PlotfullmodelButton = uibutton(app.FullModelTab, 'push');
            app.PlotfullmodelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfullmodelButtonPushed, true);
            app.PlotfullmodelButton.Text = {'Plot full model'; ''};
            app.GridCheckBox_4 = uicheckbox(app.FullModelTab);
            app.GridCheckBox_4.Text = 'Grid';
            app.SpontaneousCalciumOscillationsinAstrocytesLabel = uilabel(app.UIFigure);
            app.SpontaneousCalciumOscillationsinAstrocytesLabel.Text = 'Spontaneous Calcium Oscillations in Astrocytes';
            app.uMsLabel = uilabel(app.UIFigure);
            app.uMsLabel.Text = 'uM/s';
            app.VinEditField = uieditfield(app.UIFigure, 'numeric');
            app.VinEditField.ValueChangedFcn = createCallbackFcn(app, @VinEditFieldValueChanged, true);
            app.VinEditField.Value = 0.05;
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            app.VM2SliderLabel = uilabel(app.UIFigure);
            app.VM2SliderLabel.Tooltip = {'Maximum flux of calcium ions out of the pump'};
            app.VM2SliderLabel.Text = 'VM2';
            app.VM2Slider = uislider(app.UIFigure);
            app.VM2Slider.Limits = [1 20];
            app.VM2Slider.ValueChangedFcn = createCallbackFcn(app, @VM2SliderValueChanged, true);
            app.VM2Slider.Tooltip = {'Maximum flux of calcium ions into the ER'};
            app.VM2Slider.Value = 15;
            app.kfSliderLabel = uilabel(app.UIFigure);
            app.kfSliderLabel.Tooltip = {'Leak flux from the ER to the cytosol'; ''};
            app.kfSliderLabel.Text = 'kf';
            app.kfSlider = uislider(app.UIFigure);
            app.kfSlider.Limits = [0.1 0.3];
            app.kfSlider.ValueChangedFcn = createCallbackFcn(app, @kfSliderValueChanged, true);
            app.kfSlider.Tooltip = {'Leak flux from the ER to the cytosol'; ''};
            app.kfSlider.Value = 0.15;
            app.VM2EditField = uieditfield(app.UIFigure, 'numeric');
            app.VM2EditField.ValueChangedFcn = createCallbackFcn(app, @VM2EditFieldValueChanged, true);
            app.VM2EditField.Value = 15;
            app.kCaAEditField = uieditfield(app.UIFigure, 'numeric');
            app.kCaAEditField.Limits = [0.05 0.3];
            app.kCaAEditField.ValueChangedFcn = createCallbackFcn(app, @kCaAEditFieldValueChanged, true);
            app.kCaAEditField.Value = 0.15;
            app.kPEditField = uieditfield(app.UIFigure, 'numeric');
            app.kPEditField.ValueChangedFcn = createCallbackFcn(app, @kPEditFieldValueChanged, true);
            app.kPEditField.Value = 0.3;
            app.KpSliderLabel = uilabel(app.UIFigure);
            app.KpSliderLabel.Text = 'Kp';
            app.KpSlider = uislider(app.UIFigure);
            app.KpSlider.Limits = [0.1 0.5];
            app.KpSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            app.KpSlider.Tooltip = {'Concentration of Ca2+ required to produce a half-maximal response in the PLC positive feedback mechanism'};
            app.KpSlider.Value = 0.3;
            app.VM3SliderLabel = uilabel(app.UIFigure);
            app.VM3SliderLabel.Tooltip = {'Maximum flux of calcium ions into the cytosol'};
            app.VM3SliderLabel.Text = 'VM3';
            app.VM3Slider = uislider(app.UIFigure);
            app.VM3Slider.Limits = [5 300];
            app.VM3Slider.ValueChangedFcn = createCallbackFcn(app, @VM3SliderValueChanged, true);
            app.VM3Slider.Tooltip = {'Maximum flux of calcium ions out of ER'};
            app.VM3Slider.Value = 40;
            app.k_outSliderLabel = uilabel(app.UIFigure);
            app.k_outSliderLabel.Tooltip = {'Rate of Ca2+ out of cytosol'; ''};
            app.k_outSliderLabel.Text = 'k_out';
            app.k_outSlider = uislider(app.UIFigure);
            app.k_outSlider.Limits = [0.1 0.3];
            app.k_outSlider.ValueChangedFcn = createCallbackFcn(app, @k_outSliderValueChanged, true);
            app.k_outSlider.Tooltip = {'Inhibiting affinity of type-2 isoform'};
            app.k_outSlider.Value = 0.15;
            app.VM3EditField = uieditfield(app.UIFigure, 'numeric');
            app.VM3EditField.ValueChangedFcn = createCallbackFcn(app, @VM3EditFieldValueChanged, true);
            app.VM3EditField.Value = 40;
            app.kCaIEditField = uieditfield(app.UIFigure, 'numeric');
            app.kCaIEditField.Value = 0.15;
            app.uMsLabel_2 = uilabel(app.UIFigure);
            app.uMsLabel_2.Text = 'uM/s';
            app.uMLabel_3 = uilabel(app.UIFigure);
            app.uMLabel_3.Text = 'uM';
            app.uMLabel = uilabel(app.UIFigure);
            app.uMLabel.Text = 'uM';
            app.uMsLabel_3 = uilabel(app.UIFigure);
            app.uMsLabel_3.Text = 'uM/s';
            app.uMLabel_2 = uilabel(app.UIFigure);
            app.uMLabel_2.Text = 'uM';
            app.FlowofCalciumfromtheextracellularspacetothecytosolLabel = uilabel(app.UIFigure);
            app.FlowofCalciumfromtheextracellularspacetothecytosolLabel.Tooltip = {'Flow of Calcium from the'; ' extracellular space to the cytosol '};
            app.FlowofCalciumfromtheextracellularspacetothecytosolLabel.Text = 'Vin';
            app.VinSlider = uislider(app.UIFigure);
            app.VinSlider.Limits = [0 0.5];
            app.VinSlider.ValueChangedFcn = createCallbackFcn(app, @VinSliderValueChanged, true);
            app.VinSlider.ValueChangingFcn = createCallbackFcn(app, @VinSliderValueChanging, true);
            app.VinSlider.Tooltip = {'Flow of Calcium from the'; ' extracellular space to the cytosol '};
            app.VinSlider.Value = 0.05;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ExperimentalTracesTab = uitab(app.TabGroup2);
            app.ExperimentalTracesTab.Title = 'Experimental Traces';
            app.Tree = uitree(app.ExperimentalTracesTab, 'checkbox');
            app.ReactiveNode_2 = uitreenode(app.Tree);
            app.ReactiveNode_2.Text = 'Reactive';
            app.Trace1Node = uitreenode(app.ReactiveNode_2);
            app.Trace1Node.Text = 'Trace 1';
            app.Trace2Node = uitreenode(app.ReactiveNode_2);
            app.Trace2Node.Text = 'Trace 2';
            app.Trace3Node = uitreenode(app.ReactiveNode_2);
            app.Trace3Node.Text = 'Trace 3';
            app.PolygonalNode = uitreenode(app.Tree);
            app.PolygonalNode.Text = 'Polygonal';
            app.Trace1Node_3 = uitreenode(app.PolygonalNode);
            app.Trace1Node_3.Text = 'Trace 1';
            app.Trace2Node_3 = uitreenode(app.PolygonalNode);
            app.Trace2Node_3.Text = 'Trace 2';
            app.Trace1Node_4 = uitreenode(app.PolygonalNode);
            app.Trace1Node_4.Text = 'Trace 1';
            app.StellateNode = uitreenode(app.Tree);
            app.StellateNode.Text = 'Stellate';
            app.Trace1Node_2 = uitreenode(app.StellateNode);
            app.Trace1Node_2.Text = 'Trace 1';
            app.Trace2Node_2 = uitreenode(app.StellateNode);
            app.Trace2Node_2.Text = 'Trace 2';
            app.Trace3Node_2 = uitreenode(app.StellateNode);
            app.Trace3Node_2.Text = 'Trace 3';
            app.ExperimentalTracesDataTab_2 = uitab(app.TabGroup2);
            app.ExperimentalTracesDataTab_2.Title = 'Experimental Traces Data';
            app.Tracesdata = uitable(app.ExperimentalTracesDataTab_2);
            app.Tracesdata.ColumnName = {'Stellate'; 'Polygonal'; 'Reactive'};
            app.Tracesdata.RowName = {'Trace 1'; 'Trace 2'; 'Trace 3'};
            app.PlotCaButton = uibutton(app.UIFigure, 'push');
            app.PlotCaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCaButtonPushed, true);
            app.PlotCaButton.Text = {'Plot Ca'; ''};
            app.PlotIP3Button = uibutton(app.UIFigure, 'push');
            app.PlotIP3Button.ButtonPushedFcn = createCallbackFcn(app, @PlotIP3ButtonPushed, true);
            app.PlotIP3Button.Text = {'Plot IP3'; ''};
            app.PlotCainERButton = uibutton(app.UIFigure, 'push');
            app.PlotCainERButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCainERButtonPushed, true);
            app.PlotCainERButton.Text = 'Plot Ca in ER';
            app.GridCheckBox = uicheckbox(app.UIFigure);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Visible = 'off';
            app.GridCheckBox.Text = 'Grid';
            app.GridCheckBox_3 = uicheckbox(app.UIFigure);
            app.GridCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @GridCheckBox_3ValueChanged, true);
            app.GridCheckBox_3.Visible = 'off';
            app.GridCheckBox_3.Text = 'Grid';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SponAsMod

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end